classdef seeVR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        registerfunctoanatButton    matlab.ui.control.Button
        selectinputdirectoryButton  matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        InputdataTab                matlab.ui.container.Tab
        slice_Slider_2              matlab.ui.control.Slider
        Label_3                     matlab.ui.control.Label
        OrientationDropDown         matlab.ui.control.DropDown
        OrientationDropDownLabel    matlab.ui.control.Label
        pctileEdit                  matlab.ui.control.EditField
        pctilecutoffEditFieldLabel  matlab.ui.control.Label
        remLV                       matlab.ui.control.Button
        slice_Slider                matlab.ui.control.Slider
        Label_2                     matlab.ui.control.Label
        volume_Slider               matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        UIAxes_tSNR                 matlab.ui.control.UIAxes
        UIAxes_mask                 matlab.ui.control.UIAxes
        UIAxes_source               matlab.ui.control.UIAxes
        preprocessTab               matlab.ui.container.Tab
        Image                       matlab.ui.control.Image
    end


    properties (Access = private)
        dirs % data directories
        files % data filenames
        data % data
        dp %general data properties
    end

    methods (Access = private)

        function [] = plotSourceData(app)

            orient = app.OrientationDropDown.Value;

            %callback stored slice value
            switch orient
                case 'ORI1'
                    ratio = size(app.data.tSNR,1)/size(app.data.tSNR,2);
                    app.UIAxes_source.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    app.slice_Slider.Limits = [1 size(app.data.tSNR,3)];
                    if app.dp.hasrunORI1 == 0
                        if app.slice_Slider.Value > size(app.data.tSNR,3)
                            slice_Slider.Value = roundsize(app.data.tSNR,3)/2;
                            app.dp.hasrunORI1 = 1;
                            app.dp.hasrunORI2 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI2'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,1);
                    app.UIAxes_source.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    app.slice_Slider.Limits = [1 size(app.data.tSNR,2)];
                    if app.dp.hasrunORI2 == 0
                        if app.slice_Slider.Value > size(app.data.tSNR,2)
                            app.slice_Slider.Value = roundsize(app.data.tSNRt,2)/2;
                            app.dp.hasrunORI2 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI3'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,2);
                    app.UIAxes_source.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    if app.dp.hasrunORI3 == 0
                        app.slice_Slider.Limits = [1 size(app.data.tSNR,1)];
                        if app.slice_Slider > size(app.data.tSNR,1)
                            app.slice_Slider.Value = roundsize(app.data.tSNR,1)/2;
                            app.dp.hasrunORI3 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI2 = 0;
                        end
                    end
            end
            slice = round(get(app.slice_Slider,'Value'));
            vol = round(get(app.volume_Slider,'Value'));

            switch orient
                case 'ORI1'
                    cla(app.UIAxes_source);
                    imagesc(app.UIAxes_source,squeeze(app.data.sourceData(:,:,slice,vol)));
                case 'ORI2'
                    cla(app.UIAxes_source);
                    imagesc(app.UIAxes_source,imrotate(squeeze(app.data.sourceData(:,slice,:,vol)),90));
                case 'ORI3'
                    cla(app.UIAxes_source);
                    imagesc(app.UIAxes_source,imrotate(squeeze(app.data.sourceData(slice,:,:,vol)),90));
            end
            app.UIAxes_source.Colormap = bone(128);
        end

        % function to plot SNR map with removed voxel overlay

        function [] = plottSNR(app)

            orient = app.OrientationDropDown.Value;

            %callback stored slice value
            switch orient
                case 'ORI1'
                    ratio = size(app.data.tSNR,1)/size(app.data.tSNR,2);
                    app.UIAxes_tSNR.PlotBoxAspectRatio = [1, ratio,  1];
                    app.UIAxes_mask.PlotBoxAspectRatio = app.UIAxes_tSNR.PlotBoxAspectRatio;
                    %set new slider limits
                    app.slice_Slider_2.Limits = [1 size(app.data.tSNR,3)];
                    if app.dp.hasrunORI1 == 0
                        if app.slice_Slider_2.Value > size(app.data.tSNR,3)
                            slice_Slider_2.Value = roundsize(app.data.tSNR,3)/2;
                            app.dp.hasrunORI1 = 1;
                            app.dp.hasrunORI2 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI2'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,1);
                    app.UIAxes_tSNR.PlotBoxAspectRatio = [1  ratio  1];
                    app.UIAxes_mask.PlotBoxAspectRatio = app.UIAxes_tSNR.PlotBoxAspectRatio;
                    %set new slider limits
                    app.slice_Slider_2.Limits = [1 size(app.data.tSNR,2)];
                    if app.dp.hasrunORI2 == 0
                        if app.slice_Slider_2.Value > size(app.data.tSNR,2)
                            app.slice_Slider_2.Value = roundsize(app.data.tSNRt,2)/2;
                            app.dp.hasrunORI2 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI3'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,2);
                    app.UIAxes_tSNR.PlotBoxAspectRatio = [1, ratio,  1];
                    app.UIAxes_mask.PlotBoxAspectRatio = app.UIAxes_tSNR.PlotBoxAspectRatio;
                    %set new slider limits
                    if app.dp.hasrunORI3 == 0
                        app.slice_Slider_2.Limits = [1 size(app.data.tSNR,1)];
                        if app.slice_Slider_2 > size(app.data.tSNR,1)
                            app.slice_Slider_2.Value = roundsize(app.data.tSNR,1)/2;
                            app.dp.hasrunORI3 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI2 = 0;
                        end
                    end
            end
            slice = round(get(app.slice_Slider_2,'Value'));

            if app.dp.LVfilter == 0

                switch orient % no large vessel removal
                    case 'ORI1'
                        cla(app.UIAxes_tSNR);
                        imagesc(app.UIAxes_tSNR,squeeze(app.data.tSNR(:,:,slice)));
                    case 'ORI2'
                        cla(app.UIAxes_tSNR);
                        tmp = imrotate(squeeze(app.data.tSNR(:,slice,:)),90);
                        imagesc(app.UIAxes_tSNR,tmp); clear tmp
                    case 'ORI3'
                        cla(app.UIAxes_tSNR);
                        tmp = imrotate(squeeze(app.data.tSNR(slice,:,:)),90);
                        imagesc(app.UIAxes_tSNR,tmp); clear tmp
                end
                app.UIAxes_tSNR.Colormap = bone(128);

            else
                app.UIAxes_tSNR.CLim = [0 100];
                switch orient % overlay removed voxels
                    case 'ORI1'
                        cla(app.UIAxes_tSNR); cla(app.UIAxes_mask);
                        tmp = squeeze(app.data.tSNR(:,:,slice));
                        f1=imagesc(app.UIAxes_tSNR,tmp,"AlphaData",0.6); clear tmp
                        app.UIAxes_tSNR.Colormap = bone(128);
                        tmp = squeeze(app.data.mask.LVmask(:,:,slice));
                        tmp(tmp == 0) = NaN;
                        f2=imagesc(app.UIAxes_mask,tmp);
                        app.UIAxes_mask.Colormap = magma(2);
                        app.UIAxes_mask.Position = app.UIAxes_tSNR.Position;
                        linkaxes([app.UIAxes_tSNR app.UIAxes_mask]);
                    case 'ORI2'
                        cla(app.UIAxes_tSNR); cla(app.UIAxes_mask);
                        tmp = imrotate(squeeze(app.data.tSNR(:,slice,:)),90);
                        f1=imagesc(app.UIAxes_tSNR,tmp,"AlphaData",0.6); clear tmp
                        app.UIAxes_tSNR.Colormap = bone(128);
                        tmp = imrotate(squeeze(app.data.mask.LVmask(:,slice,:)),90);
                        f2=imagesc(app.UIAxes_mask,tmp);
                        app.UIAxes_mask.Colormap = magma(2);
                        app.UIAxes_mask.Position = app.UIAxes_tSNR.Position;
                        linkaxes([app.UIAxes_tSNR app.UIAxes_mask]);
                    case 'ORI3'
                        cla(app.UIAxes_tSNR); cla(app.UIAxes_mask);
                        tmp = imrotate(squeeze(app.data.tSNR(slice,:,:)),90);
                        f1=imagesc(app.UIAxes_tSNR,tmp,"AlphaData",0.6); clear tmp
                        app.UIAxes_tSNR.Colormap = bone(128);
                        tmp = imrotate(squeeze(app.data.mask.LVmask(slice,:,:)),90);
                        f2=imagesc(app.UIAxes_mask,tmp);
                        app.UIAxes_mask.Colormap = magma(2);
                        app.UIAxes_mask.Position = app.UIAxes_tSNR.Position;
                        linkaxes([app.UIAxes_tSNR app.UIAxes_mask]);
                end
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %run pathdef function
            global opts;
            [dirs, files] = seeVR_set_variables;
            % set folder and filename tags
            app.dirs = dirs;
            app.files = files;
        end

        % Button pushed function: selectinputdirectoryButton
        function selectinputdirectoryButtonPushed(app, event)
            %initialize the global opts structure
            global opts;
            %select input directory
            app.dirs.datapath = fullfile(uigetdir);
            [app.data, app.dp] = check_loadData(app.dirs, app.files);
            %calculate temporal SNR
            [voxel_ts, coordinates] = grabTimeseries(app.data.sourceData, app.data.mask.WB);
            SD = nanstd(voxel_ts,0,2);
            MN = nanmean(voxel_ts,2);
            tSNR = zeros([1 numel(app.data.mask.WB)]);
            tSNR(coordinates) = MN./SD;
            app.data.tSNR = reshape(tSNR, size(app.data.mask.WB));

            % set visibility after loading
            app.UIAxes_source.Visible = 'on';
            app.volume_Slider.Visible = 'on';
            app.slice_Slider.Visible = 'on';
            app.slice_Slider_2.Visible = 'on';
            app.remLV.Visible = 'on';
            app.pctileEdit.Visible = 'on';
            app.OrientationDropDown.Visible = 'on';

            if app.dp.isT1; app.registerfunctoanatButton.Visible = 'on'; end

            %set limits of volume and slice sliders
            set(app.volume_Slider,'Limits',[1 app.dp.vols]);
            set(app.volume_Slider,'Value',1);
            set(app.slice_Slider,'Limits',[1 app.dp.zdim]);
            set(app.slice_Slider,'Value',ceil(app.dp.zdim/2));
            set(app.slice_Slider_2,'Limits',[1 app.dp.zdim]);
            set(app.slice_Slider_2,'Value',ceil(app.dp.zdim/2));

            %display source image
            app.dp.hasrunORI1 = 0; app.dp.hasrunORI2 = 0; app.dp.hasrunORI3 = 0;
            app.dp.LVfilter = 0;
            plotSourceData(app)
            plottSNR(app)

        end

        % Value changed function: volume_Slider
        function volume_SliderValueChanged(app, event)
            value = round(app.volume_Slider.Value);
            plotSourceData(app)
            disp(['volume number: ',int2str(value)])
        end

        % Value changed function: slice_Slider
        function slice_SliderValueChanged(app, event)
            value = round(app.slice_Slider.Value);
            plotSourceData(app)
            disp(['slice number: ',int2str(value)])
        end

        % Button pushed function: registerfunctoanatButton
        function registerfunctoanatButtonPushed(app, event)
            % register mean BOLD data to anatomical via dedicated UI
            global opts;
            func2anat_lin(app.data.sourceData,app.data.anat,app.dirs.elastix,opts);

        end

        % Value changed function: OrientationDropDown
        function OrientationDropDownValueChanged(app, event)
            plotSourceData(app)
            plottSNR(app)
        end

        % Value changed function: slice_Slider_2
        function slice_Slider_2ValueChanged(app, event)
            value = round(app.slice_Slider_2.Value);
            plottSNR(app)
            disp(['slice number: ',int2str(value)])
        end

        % Button pushed function: remLV
        function remLVButtonPushed(app, event)
            global opts;
            opts.LVpercentile = str2double(app.pctileEdit.Value);
            opts.verbose = 0;
            [app.data.mask.mmask] = remLV(imrotate(app.data.sourceData,270),imrotate(app.data.mask.WB,270),opts);
            app.data.mask.mmask = logical(imrotate(app.data.mask.mmask,90));
            app.dp.LVfilter = 1; 
            % calculate LVmask
            app.data.mask.LVmask = abs(app.data.mask.WB - app.data.mask.mmask).*app.data.mask.WB;
            plottSNR(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'seeVR.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputdataTab = uitab(app.TabGroup);
            app.InputdataTab.Title = 'Input data';
            app.UIAxes_source = uiaxes(app.InputdataTab);
            app.UIAxes_source.XTick = [];
            app.UIAxes_source.YTick = [];
            app.UIAxes_source.Visible = 'off';
            app.UIAxes_mask = uiaxes(app.InputdataTab);
            app.UIAxes_mask.XTick = [];
            app.UIAxes_mask.YTick = [];
            app.UIAxes_mask.Visible = 'off';
            app.UIAxes_tSNR = uiaxes(app.InputdataTab);
            app.UIAxes_tSNR.CLim = [0 100];
            app.UIAxes_tSNR.XTick = [];
            app.UIAxes_tSNR.YTick = [];
            app.UIAxes_tSNR.Visible = 'off';
            app.Label = uilabel(app.InputdataTab);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.volume_Slider = uislider(app.InputdataTab);
            app.volume_Slider.Limits = [1 100];
            app.volume_Slider.MajorTicks = [];
            app.volume_Slider.ValueChangedFcn = createCallbackFcn(app, @volume_SliderValueChanged, true);
            app.volume_Slider.MinorTicks = [];
            app.volume_Slider.Visible = 'off';
            app.volume_Slider.Value = 1;
            app.Label_2 = uilabel(app.InputdataTab);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.slice_Slider = uislider(app.InputdataTab);
            app.slice_Slider.Limits = [1 100];
            app.slice_Slider.MajorTicks = [];
            app.slice_Slider.ValueChangedFcn = createCallbackFcn(app, @slice_SliderValueChanged, true);
            app.slice_Slider.MinorTicks = [];
            app.slice_Slider.Visible = 'off';
            app.slice_Slider.Value = 1;
            app.remLV = uibutton(app.InputdataTab, 'push');
            app.remLV.ButtonPushedFcn = createCallbackFcn(app, @remLVButtonPushed, true);
            app.remLV.Visible = 'off';
            app.remLV.Tooltip = {'voxels are sorted in terms of their noise-to-signal values and thresholded based on the supplied percentile cut-off value'};
            app.remLV.Text = 'remove large vessels';
            app.pctilecutoffEditFieldLabel = uilabel(app.InputdataTab);
            app.pctilecutoffEditFieldLabel.Visible = 'off';
            app.pctilecutoffEditFieldLabel.Text = 'pctile cut-off';
            app.pctileEdit = uieditfield(app.InputdataTab, 'text');
            app.pctileEdit.Visible = 'off';
            app.pctileEdit.Value = '98';
            app.OrientationDropDownLabel = uilabel(app.InputdataTab);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.InputdataTab);
            app.OrientationDropDown.Items = {'ORI1', 'ORI2', 'ORI3'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationDropDownValueChanged, true);
            app.OrientationDropDown.Visible = 'off';
            app.OrientationDropDown.Tooltip = {'select image orientation'};
            app.OrientationDropDown.Value = 'ORI1';
            app.Label_3 = uilabel(app.InputdataTab);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.slice_Slider_2 = uislider(app.InputdataTab);
            app.slice_Slider_2.Limits = [1 100];
            app.slice_Slider_2.MajorTicks = [];
            app.slice_Slider_2.ValueChangedFcn = createCallbackFcn(app, @slice_Slider_2ValueChanged, true);
            app.slice_Slider_2.MinorTicks = [];
            app.slice_Slider_2.Visible = 'off';
            app.slice_Slider_2.Value = 1;
            app.preprocessTab = uitab(app.TabGroup);
            app.preprocessTab.Title = 'pre-process';
            app.selectinputdirectoryButton = uibutton(app.UIFigure, 'push');
            app.selectinputdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @selectinputdirectoryButtonPushed, true);
            app.selectinputdirectoryButton.Text = 'select input directory';
            app.registerfunctoanatButton = uibutton(app.UIFigure, 'push');
            app.registerfunctoanatButton.ButtonPushedFcn = createCallbackFcn(app, @registerfunctoanatButtonPushed, true);
            app.registerfunctoanatButton.Visible = 'off';
            app.registerfunctoanatButton.Text = 'register func to anat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seeVR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end